classdef DehazeAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DehazeGUIUIFigure  matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        SLabel             matlab.ui.control.Label
        Sigma              matlab.ui.control.Slider
        LLabel             matlab.ui.control.Label
        gammaL             matlab.ui.control.Slider
        SelectImage        matlab.ui.control.DropDown
        SelectImageLabel   matlab.ui.control.Label
        HLabel             matlab.ui.control.Label
        gammaH             matlab.ui.control.Slider
        ClearButton        matlab.ui.control.Button
        DehazeButton       matlab.ui.control.Button
        Title              matlab.ui.control.Label
        ChooseFilterLabel  matlab.ui.control.Label
        SelectFilter       matlab.ui.control.DropDown
        SetDefaultParam    matlab.ui.control.Button
        Copyrights         matlab.ui.control.Label
        Image              matlab.ui.control.Image
        HVal               matlab.ui.control.Label
        SVal               matlab.ui.control.Label
        LVal               matlab.ui.control.Label
    end

 
    methods (Access = private)
        
        function set_sliders(app,sigma,gammaH,gammaL)
            app.Sigma.Value=sigma;
            app.gammaH.Value=gammaH;
            app.gammaL.Value=gammaL;
            app.SVal.Text = num2str(app.Sigma.Value); 
            app.LVal.Text = num2str(app.gammaL.Value);
            app.HVal.Text = num2str(app.gammaH.Value);
        end
        
        function setdefaults(app)
            if      app.SelectImage.Value=="Image1.png"
                set_sliders(app,50,6,0.85)
            elseif  app.SelectImage.Value=="Image2.png"
                set_sliders(app,90,6,0.8)
            elseif  app.SelectImage.Value=="Image3.png"
                set_sliders(app,100,4,0.8)
            elseif  app.SelectImage.Value=="Image4.png"
                set_sliders(app,100,6,1)				
            elseif  app.SelectImage.Value=="Image5.png"
                set_sliders(app,120,5.5,0.9)
            elseif  app.SelectImage.Value=="Image6.png"
                set_sliders(app,120,5.5,0.7)
            elseif  app.SelectImage.Value=="Image7.png"
                set_sliders(app,120,5.5,0.9)
            elseif  app.SelectImage.Value=="Image8.png"
                set_sliders(app,120,5.5,0.8)
            elseif  app.SelectImage.Value=="Image9.png"
                set_sliders(app,120,4,0.8)
            elseif  app.SelectImage.Value=="Image10.png"
                set_sliders(app,100,6,0.7)
            elseif  app.SelectImage.Value=="Image11.png"
                set_sliders(app,120,5.5,0.8)
            elseif  app.SelectImage.Value=="Image12.png"
                set_sliders(app,120,5.5,0.9)
            elseif  app.SelectImage.Value=="Image13.png"
                set_sliders(app,120,5.5,0.9)
            elseif  app.SelectImage.Value=="Image14.png"
                set_sliders(app,100,4,0.8)
            elseif  app.SelectImage.Value=="Image15.png"
                set_sliders(app,120,6,1)				
            elseif  app.SelectImage.Value=="Image16.png"
                set_sliders(app,120,4,0.8)
            elseif  app.SelectImage.Value=="Image17.png"
                set_sliders(app,100,6,0.9)
            elseif  app.SelectImage.Value=="Image18.png"
                set_sliders(app,100,6,0.8)
            elseif  app.SelectImage.Value=="Image19.png"
                set_sliders(app,100,6,0.9)
            elseif  app.SelectImage.Value=="Image20.png"
                set_sliders(app,100,6,1)
            elseif  app.SelectImage.Value=="Image21.png"
                set_sliders(app,100,4,0.8)
            elseif  app.SelectImage.Value=="Image22.png"
                set_sliders(app,100,4,1)
            elseif  app.SelectImage.Value=="Image23.png"
                set_sliders(app,120,6,1)
            elseif  app.SelectImage.Value=="Image24.png"
                set_sliders(app,100,6,1)
            elseif  app.SelectImage.Value=="Image25.png"
                set_sliders(app,120,5,1) 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setdefaults(app);
        end

        % Value changed function: SelectImage
        function SelectImageValueChanged(app, event)
            setdefaults(app);
            app.Image.ImageSource=app.SelectImage.Value;
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all;
        end

        % Button pushed function: DehazeButton
        function DehazeButtonPushed(app, event)
            if app.SelectFilter.Value == 'G'
                bilateraldehaze(app.SelectImage.Value); 
            else
                homomorphicFilter(app.SelectImage.Value,app.Sigma.Value,app.gammaH.Value,app.gammaL.Value);            
            end
        end

        % Value changed function: SelectFilter
        function SelectFilterValueChanged(app, event)
           if  app.SelectFilter.Value=='G'
               app.Sigma.Visible = 'off';
               app.gammaH.Visible = 'off';
               app.gammaL.Visible = 'off';
               app.SLabel.Visible = 'off';
               app.HLabel.Visible = 'off';
               app.LLabel.Visible = 'off';  
               app.LVal.Visible = 'off'; 
               app.HVal.Visible = 'off'; 
               app.SVal.Visible = 'off'; 
               app.SetDefaultParam.Visible = 'off';
           else
               app.Sigma.Visible = 'on'; 
               app.gammaH.Visible = 'on';
               app.gammaL.Visible = 'on';
               app.SLabel.Visible = 'on';
               app.HLabel.Visible = 'on';
               app.LLabel.Visible = 'on';
               app.LVal.Visible = 'on'; 
               app.HVal.Visible = 'on'; 
               app.SVal.Visible = 'on'; 
               app.SetDefaultParam.Visible = 'on';               
           end            
        end

        % Button pushed function: SetDefaultParam
        function SetDefaultParamPushed(app, event)
            setdefaults(app);
        end

        % Value changed function: Sigma
        function SigmaValueChanged(app, event)
            app.SVal.Text = num2str(round(app.Sigma.Value,2));            
        end

        % Value changed function: gammaL
        function gammaLValueChanged(app, event)
            app.LVal.Text = num2str(round(app.gammaL.Value,2));            
        end

        % Value changed function: gammaH
        function gammaHValueChanged(app, event)
            app.HVal.Text = num2str(round(app.gammaH.Value,2));            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DehazeGUIUIFigure = uifigure('Visible', 'off');
            app.DehazeGUIUIFigure.Name = 'Dehaze GUI';
            app.GridLayout = uigridlayout(app.DehazeGUIUIFigure);
            app.SLabel = uilabel(app.GridLayout);
            app.SLabel.Text = 'σ';
            app.Sigma = uislider(app.GridLayout);
            app.Sigma.Limits = [40 120];
            app.Sigma.MajorTicks = [40 60 80 100 120];
            app.Sigma.MajorTickLabels = {'40', '60', '80', '100', '120'};
            app.Sigma.ValueChangedFcn = createCallbackFcn(app, @SigmaValueChanged, true);
            app.Sigma.MinorTicks = [40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100 102 104 106 108 110 112 114 116 118 120];
            app.Sigma.Value = 50;
            app.LLabel = uilabel(app.GridLayout);
            app.LLabel.Text = 'ΓL';
            app.gammaL = uislider(app.GridLayout);
            app.gammaL.Limits = [0 1];
            app.gammaL.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.gammaL.ValueChangedFcn = createCallbackFcn(app, @gammaLValueChanged, true);
            app.gammaL.Value = 0.85;
            app.SelectImage = uidropdown(app.GridLayout);
            app.SelectImage.Items = {'Image1', 'Image2', 'Image3', 'Image4', 'Image5', 'Image6', 'Image7', 'Image8', 'Image9', 'Image10', 'Image11', 'Image12', 'Image13', 'Image14', 'Image15', 'Image16', 'Image17', 'Image18', 'Image19', 'Image20', 'Image21', 'Image22', 'Image23', 'Image24', 'Image25'};
            app.SelectImage.ItemsData = {'Image1.png', 'Image2.png', 'Image3.png', 'Image4.png', 'Image5.png', 'Image6.png', 'Image7.png', 'Image8.png', 'Image9.png', 'Image10.png', 'Image11.png', 'Image12.png', 'Image13.png', 'Image14.png', 'Image15.png', 'Image16.png', 'Image17.png', 'Image18.png', 'Image19.png', 'Image20.png', 'Image21.png', 'Image22.png', 'Image23.png', 'Image24.png', 'Image25.png'};
            app.SelectImage.ValueChangedFcn = createCallbackFcn(app, @SelectImageValueChanged, true);
            app.SelectImage.Value = 'Image1.png';
            app.SelectImageLabel = uilabel(app.GridLayout);
            app.SelectImageLabel.Text = 'Select Image :';
            app.HLabel = uilabel(app.GridLayout);
            app.HLabel.Text = 'ΓH ';
            app.gammaH = uislider(app.GridLayout);
            app.gammaH.Limits = [2 6];
            app.gammaH.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.gammaH.ValueChangedFcn = createCallbackFcn(app, @gammaHValueChanged, true);
            app.gammaH.Value = 6;
            app.ClearButton = uibutton(app.GridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DehazeButton = uibutton(app.GridLayout, 'push');
            app.DehazeButton.ButtonPushedFcn = createCallbackFcn(app, @DehazeButtonPushed, true);
            app.DehazeButton.Text = 'Dehaze';
            app.Title = uilabel(app.GridLayout);
            app.Title.Text = 'Homomorphic filter VS Guided joint Bilateral filter Dehazing';
            app.ChooseFilterLabel = uilabel(app.GridLayout);
            app.ChooseFilterLabel.Text = 'Choose Filter : ';
            app.SelectFilter = uidropdown(app.GridLayout);
            app.SelectFilter.Items = {'Homomorphic', 'Guided joint Bilateral', ''};
            app.SelectFilter.ItemsData = {'H', 'G'};
            app.SelectFilter.ValueChangedFcn = createCallbackFcn(app, @SelectFilterValueChanged, true);
            app.SelectFilter.Value = 'H';
            app.SetDefaultParam = uibutton(app.GridLayout, 'push');
            app.SetDefaultParam.ButtonPushedFcn = createCallbackFcn(app, @SetDefaultParamPushed, true);
            app.SetDefaultParam.Text = 'Default ';
            app.Copyrights = uilabel(app.GridLayout);
            app.Copyrights.Text = 'Shay Karadi & Tamir Karamani ©';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'Image1.png';
            app.HVal = uilabel(app.GridLayout);
            app.HVal.Text = '';
            app.SVal = uilabel(app.GridLayout);
            app.SVal.Text = '';
            app.LVal = uilabel(app.GridLayout);
            app.LVal.Text = '';
            % Show the figure after all components are created
            app.DehazeGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DehazeAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DehazeGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DehazeGUIUIFigure)
        end
    end
end